classdef ps_video_labeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        JumptolabeledframeSpinner       matlab.ui.control.Spinner
        JumptolabeledframeSpinnerLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        LatestlabeledframeButton        matlab.ui.control.Button
        LastlabeledframeButton          matlab.ui.control.Button
        LoaddataButton                  matlab.ui.control.Button
        secforwardButton                matlab.ui.control.Button
        secbackButton                   matlab.ui.control.Button
        framesforwardButton             matlab.ui.control.Button
        framesbackButton                matlab.ui.control.Button
        NextframeButton                 matlab.ui.control.Button
        PreviousframeButton             matlab.ui.control.Button
        SlowerButton                    matlab.ui.control.Button
        FasterButton                    matlab.ui.control.Button
        SavedataButton                  matlab.ui.control.Button
        LabelUnlabelframeButton         matlab.ui.control.Button
        PlayvideoButton                 matlab.ui.control.StateButton
        OpenvideoButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties
        vidObj;
        vidSpeed = 1;
        vidName = '';
        frameOffset = 0;
        marked_frames = [];
    end
    
    methods (Access = private)
        
        function playVid_func(app)
            focus(app.UIFigure);
            while(hasFrame(app.vidObj))
                frame = readFrame(app.vidObj);
                imshow(frame,'Parent',app.UIAxes);
                frameIdx = getFrameIdx_func(app);
                xlabel(app.UIAxes,sprintf('Current Time = %.3f sec 	\n Frame = %i / %i', ...
                                          app.vidObj.CurrentTime, frameIdx, app.vidObj.NumFrames));
                if any(app.marked_frames == (frameIdx-1))
                    app.UIAxes.XColor = 'r';
                    app.UIAxes.FontSize = 20;
                else
                    app.UIAxes.XColor = 'k';
                    app.UIAxes.FontSize = 12;
                end

                if app.vidSpeed < 1
                    drawnow limitrate;
                else
                    plot_for = 1/app.vidObj.FrameRate;
                    pause(plot_for * app.vidSpeed);
                end
                
                if ~app.PlayvideoButton.Value 
                    break;
                end
            end
        end

        function frameIdx = getFrameIdx_func(app) % Gets index of the current video frame
            frameIdx = round(app.vidObj.CurrentTime * app.vidObj.FrameRate + app.frameOffset);
        end
        
        function playButton_func(app,ispressed)
            if ispressed
                app.PlayvideoButton.Text = 'Pause video';

                app.OpenvideoButton.Enable = false;
                app.LabelUnlabelframeButton.Enable = false;
                app.PreviousframeButton.Enable = false;
                app.NextframeButton.Enable = false;
                app.framesbackButton.Enable = false;
                app.framesforwardButton.Enable = false;
                app.secbackButton.Enable = false;
                app.secforwardButton.Enable = false;
                app.JumptolabeledframeSpinnerLabel.Enable = false;
                app.JumptolabeledframeSpinner.Enable = false;
                app.TextArea.Enable = false;
                app.LatestlabeledframeButton.Enable = false;
                app.LastlabeledframeButton.Enable = false;
                app.LoaddataButton.Enable = false;
                app.SavedataButton.Enable = false;

                app.playVid_func;

                focus(app.UIFigure);

                drawnow;
            else
                app.PlayvideoButton.Text = 'Play video';

                app.OpenvideoButton.Enable = true;
                app.LabelUnlabelframeButton.Enable = true;
                app.PreviousframeButton.Enable = true;
                app.NextframeButton.Enable = true;
                app.framesbackButton.Enable = true;
                app.framesforwardButton.Enable = true;
                app.secbackButton.Enable = true;
                app.secforwardButton.Enable = true;
                app.JumptolabeledframeSpinnerLabel.Enable = true;
                app.JumptolabeledframeSpinner.Enable = true;
                app.TextArea.Enable = true;
                app.LatestlabeledframeButton.Enable = true;
                app.LastlabeledframeButton.Enable = true;
                app.LoaddataButton.Enable = true;
                app.SavedataButton.Enable = true;

                focus(app.UIFigure);

                drawnow;
            end
        end

        function labelUnlabel_func(app)
            if ~any(app.marked_frames == getFrameIdx_func(app))
                app.marked_frames = [app.marked_frames, getFrameIdx_func(app)];
                app.UIAxes.XColor = 'r';
                app.UIAxes.FontSize = 20;
            else
                app.marked_frames(app.marked_frames == getFrameIdx_func(app)) = [];
                app.UIAxes.XColor = 'k';
                app.UIAxes.FontSize = 12;
            end

            nLabs = length(unique(app.marked_frames));
            if nLabs < 1
                app.JumptolabeledframeSpinner.Limits = [0, 1];
            else
                app.JumptolabeledframeSpinner.Limits = [0, nLabs];
            end
            app.TextArea.Value = ['/', num2str(nLabs)];

            focus(app.UIFigure);

            drawnow;
        end

        function prevLabel_func(app)
            lab_idx = app.JumptolabeledframeSpinner.Value - 1;
            limits = app.JumptolabeledframeSpinner.Limits;

            if lab_idx <= limits(1) || lab_idx > limits(2)
                return;
            end

            app.JumptolabeledframeSpinner.Value = lab_idx;

            marked_frames = unique(app.marked_frames); % also sorts
            frameIdx = marked_frames(lab_idx);
            frame = read(app.vidObj,frameIdx);
            imshow(frame,'Parent',app.UIAxes);
            xlabel(app.UIAxes,sprintf('Current Time = %.3f sec 	\n Frame = %i / %i', ...
                                          app.vidObj.CurrentTime, frameIdx, app.vidObj.NumFrames));
            app.UIAxes.XColor = 'r';
            app.UIAxes.FontSize = 20;

            focus(app.UIFigure);

            drawnow;

        end
        
        function nextLabel_func(app)
            lab_idx = app.JumptolabeledframeSpinner.Value + 1;
            limits = app.JumptolabeledframeSpinner.Limits;

            if lab_idx <= limits(1) || lab_idx > limits(2)
                return;
            end

            app.JumptolabeledframeSpinner.Value = lab_idx;

            marked_frames = unique(app.marked_frames); % also sorts
            frameIdx = marked_frames(lab_idx);
            frame = read(app.vidObj,frameIdx);
            imshow(frame,'Parent',app.UIAxes);
            xlabel(app.UIAxes,sprintf('Current Time = %.3f sec 	\n Frame = %i / %i', ...
                                          app.vidObj.CurrentTime, frameIdx, app.vidObj.NumFrames));
            app.UIAxes.XColor = 'r';
            app.UIAxes.FontSize = 20;

            focus(app.UIFigure);

            drawnow;
        end

        function prevFrame_func(app)
            frameIdx = getFrameIdx_func(app);
            frame = read(app.vidObj,frameIdx - 1);
            imshow(frame,'Parent',app.UIAxes);
            xlabel(app.UIAxes,sprintf('Current Time = %.3f sec 	\n Frame = %i / %i', ...
                                          app.vidObj.CurrentTime, frameIdx-1, app.vidObj.NumFrames));

            if any(app.marked_frames == (frameIdx-1))
                app.UIAxes.XColor = 'r';
                app.UIAxes.FontSize = 20;
            else
                app.UIAxes.XColor = 'k';
                app.UIAxes.FontSize = 12;
            end

            focus(app.UIFigure);

            drawnow;
        end
        
        function nextFrame_func(app)
            frameIdx = getFrameIdx_func(app);
            frame = read(app.vidObj,frameIdx + 1);
            imshow(frame,'Parent',app.UIAxes);
            xlabel(app.UIAxes,sprintf('Current Time = %.3f sec 	\n Frame = %i / %i', ...
                                          app.vidObj.CurrentTime, frameIdx+1, app.vidObj.NumFrames));

            if any(app.marked_frames == (frameIdx+1))
                app.UIAxes.XColor = 'r';
                app.UIAxes.FontSize = 20;
            else
                app.UIAxes.XColor = 'k';
                app.UIAxes.FontSize = 12;
            end

            focus(app.UIFigure);

            drawnow;
        end
        
        function framesBack_func(app)
            frameIdx = getFrameIdx_func(app);
            frame = read(app.vidObj,frameIdx - 10);
            imshow(frame,'Parent',app.UIAxes);
            xlabel(app.UIAxes,sprintf('Current Time = %.3f sec 	\n Frame = %i / %i', ...
                                          app.vidObj.CurrentTime, frameIdx-10, app.vidObj.NumFrames));

            if any(app.marked_frames == (frameIdx-10))
                app.UIAxes.XColor = 'r';
                app.UIAxes.FontSize = 20;
            else
                app.UIAxes.XColor = 'k';
                app.UIAxes.FontSize = 12;
            end

            focus(app.UIFigure);

            drawnow;
        end
      
        function framesForward_func(app)
            frameIdx = getFrameIdx_func(app);
            frame = read(app.vidObj,frameIdx + 10);
            imshow(frame,'Parent',app.UIAxes);
            xlabel(app.UIAxes,sprintf('Current Time = %.3f sec 	\n Frame = %i / %i', ...
                                          app.vidObj.CurrentTime, frameIdx+10, app.vidObj.NumFrames));

            if any(app.marked_frames == (frameIdx+10))
                app.UIAxes.XColor = 'r';
                app.UIAxes.FontSize = 20;
            else
                app.UIAxes.XColor = 'k';
                app.UIAxes.FontSize = 12;
            end

            focus(app.UIFigure);

            drawnow;
        end
        
        function secBack_func(app)
            app.vidObj.CurrentTime = app.vidObj.CurrentTime - 1;
            frameIdx = getFrameIdx_func(app);
            frame = read(app.vidObj,frameIdx);
            imshow(frame,'Parent',app.UIAxes);
            xlabel(app.UIAxes,sprintf('Current Time = %.3f sec 	\n Frame = %i / %i', ...
                                          app.vidObj.CurrentTime, frameIdx, app.vidObj.NumFrames));

            if any(app.marked_frames == (frameIdx))
                app.UIAxes.XColor = 'r';
                app.UIAxes.FontSize = 20;
            else
                app.UIAxes.XColor = 'k';
                app.UIAxes.FontSize = 12;
            end

            focus(app.UIFigure);

            drawnow;
        end
        
        function secForward_func(app)
            app.vidObj.CurrentTime = app.vidObj.CurrentTime + 1;
            frameIdx = getFrameIdx_func(app);
            frame = read(app.vidObj,frameIdx);
            imshow(frame,'Parent',app.UIAxes);
            xlabel(app.UIAxes,sprintf('Current Time = %.3f sec 	\n Frame = %i / %i', ...
                                          app.vidObj.CurrentTime, frameIdx, app.vidObj.NumFrames));

            if any(app.marked_frames == (frameIdx))
                app.UIAxes.XColor = 'r';
                app.UIAxes.FontSize = 20;
            else
                app.UIAxes.XColor = 'k';
                app.UIAxes.FontSize = 12;
            end

            focus(app.UIFigure);

            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenvideoButton
        function OpenvideoButtonPushed(app, event)
            [vid_file,vid_folder] = uigetfile('*.*');
            app.vidObj = VideoReader(fullfile(vid_folder,vid_file));

            frame = read(app.vidObj,1);
            imshow(frame,'Parent',app.UIAxes);
            xlabel(app.UIAxes,sprintf('Current Time = %.3f sec 	\n Frame = %i / %i', ...
                                          app.vidObj.CurrentTime, 1, app.vidObj.NumFrames));
            est_frame = round(app.vidObj.CurrentTime * app.vidObj.FrameRate);
            app.frameOffset = round(1 - est_frame);

            app.LabelUnlabelframeButton.Enable = true;
            app.PlayvideoButton.Enable = true;
            app.FasterButton.Enable = true;
            app.SlowerButton.Enable = true;
            app.PreviousframeButton.Enable = true;
            app.NextframeButton.Enable = true;
            app.framesbackButton.Enable = true;
            app.framesforwardButton.Enable = true;
            app.secbackButton.Enable = true;
            app.secforwardButton.Enable = true;
            app.JumptolabeledframeSpinnerLabel.Enable = true;
            app.JumptolabeledframeSpinner.Enable = true;
            app.TextArea.Enable = true;
            app.LatestlabeledframeButton.Enable = true;
            app.LastlabeledframeButton.Enable = true;

            [~,vid_name] = fileparts(vid_file);
            title(app.UIAxes,[vid_name, ' 1/1 Speed'], ...
                'FontSize',14,'Color','b','Interpreter','none');
            app.vidName = vid_name;

            focus(app.UIFigure);

            drawnow;
        end

        % Value changed function: PlayvideoButton
        function PlayvideoButtonValueChanged(app, event)
            ispressed = app.PlayvideoButton.Value;
            focus(app.UIFigure);
            playButton_func(app,ispressed);            
        end

        % Button pushed function: LabelUnlabelframeButton
        function LabelUnlabelframeButtonPushed(app, event)
            labelUnlabel_func(app);
            focus(app.UIFigure);
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            labeledFrames = app.marked_frames;
            labeledFrames = unique(labeledFrames); % also sorts them
            focus(app.UIFigure);
            uisave('labeledFrames', [app.vidName,'_labels']);
        end

        % Button pushed function: SlowerButton
        function SlowerButtonPushed(app, event)
            app.vidSpeed = app.vidSpeed * 2;
            title(app.UIAxes, sprintf([app.vidName, ' 1/%i Speed'], app.vidSpeed), ...
                'FontSize',14,'Color','b','Interpreter','none');
            focus(app.UIFigure);
        end

        % Button pushed function: FasterButton
        function FasterButtonPushed(app, event)
            if app.vidSpeed >= 1
                app.vidSpeed = app.vidSpeed / 2;
                if app.vidSpeed >= 1
                    title(app.UIAxes, sprintf([app.vidName, ' 1/%i Speed'], app.vidSpeed), ...
                        'FontSize',14,'Color','b','Interpreter','none');
                else
                    title(app.UIAxes, [app.vidName, ' Max Speed'], ...
                        'FontSize',14,'Color','b','Interpreter','none');
                end
            end
            focus(app.UIFigure);
        end

        % Button pushed function: PreviousframeButton
        function PreviousframeButtonPushed(app, event)
            prevFrame_func(app);
            focus(app.UIFigure);
        end

        % Button pushed function: NextframeButton
        function NextframeButtonPushed(app, event)
            nextFrame_func(app);
            focus(app.UIFigure);
        end

        % Button pushed function: framesbackButton
        function framesbackButtonPushed(app, event)
            framesBack_func(app);
            focus(app.UIFigure);
        end

        % Button pushed function: framesforwardButton
        function framesforwardButtonPushed(app, event)
            framesForward_func(app);
            focus(app.UIFigure);
        end

        % Button pushed function: secbackButton
        function secbackButtonPushed(app, event)
            secBack_func(app);
            focus(app.UIFigure);
        end

        % Button pushed function: secforwardButton
        function secforwardButtonPushed(app, event)
            secForward_func(app);
            focus(app.UIFigure);
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            [data_file,data_folder] = uigetfile('*.*');
            load(fullfile(data_folder,data_file), 'labeledFrames');
            app.marked_frames = [app.marked_frames,labeledFrames];
            nLabs = length(unique(app.marked_frames));
            app.JumptolabeledframeSpinner.Limits = [0, nLabs];
            app.TextArea.Value = ['/', num2str(nLabs)];

            focus(app.UIFigure);

            drawnow;
        end

        % Button pushed function: LastlabeledframeButton
        function LastlabeledframeButtonPushed(app, event)
            frameIdx = max(app.marked_frames);
            frame = read(app.vidObj,frameIdx);
            imshow(frame,'Parent',app.UIAxes);
            xlabel(app.UIAxes,sprintf('Current Time = %.3f sec 	\n Frame = %i / %i', ...
                                          app.vidObj.CurrentTime, frameIdx, app.vidObj.NumFrames));
            app.UIAxes.XColor = 'r';
            app.UIAxes.FontSize = 20;

            focus(app.UIFigure);

            drawnow;
        end

        % Button pushed function: LatestlabeledframeButton
        function LatestlabeledframeButtonPushed(app, event)
            frameIdx = app.marked_frames(end);
            frame = read(app.vidObj,frameIdx);
            imshow(frame,'Parent',app.UIAxes);
            xlabel(app.UIAxes,sprintf('Current Time = %.3f sec 	\n Frame = %i / %i', ...
                                          app.vidObj.CurrentTime, frameIdx, app.vidObj.NumFrames));
            app.UIAxes.XColor = 'r';
            app.UIAxes.FontSize = 20;

            focus(app.UIFigure);

            drawnow;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            modifier = event.Modifier;

            switch(key)
                case 'space'
                    app.PlayvideoButton.Value = ~app.PlayvideoButton.Value;
                    playButton_func(app, app.PlayvideoButton.Value);
                case 'return'
                    labelUnlabel_func(app);
                case 'uparrow'
                    nextLabel_func(app);
                case 'downarrow'
                    prevLabel_func(app);
                case 'rightarrow'
                    if isempty(modifier)
                        nextFrame_func(app);
                    elseif strcmp('control',modifier{:})
                        framesForward_func(app);
                    elseif strcmp('shift',modifier{:})
                        secForward_func(app)
                    end
                case 'leftarrow'
                    if isempty(modifier)
                        prevFrame_func(app);
                    elseif strcmp('control',modifier{:})
                        framesBack_func(app);
                    elseif strcmp('shift',modifier{:})
                        secBack_func(app);
                    end
            end
            
            focus(app.UIFigure);
        end

        % Value changing function: JumptolabeledframeSpinner
        function JumptolabeledframeSpinnerValueChanging(app, event)
            lab_idx = event.Value;
            marked_frames = unique(app.marked_frames); % also sorts
            frameIdx = marked_frames(lab_idx);
            frame = read(app.vidObj,frameIdx);
            imshow(frame,'Parent',app.UIAxes);
            xlabel(app.UIAxes,sprintf('Current Time = %.3f sec 	\n Frame = %i / %i', ...
                                          app.vidObj.CurrentTime, frameIdx, app.vidObj.NumFrames));
            app.UIAxes.XColor = 'r';
            app.UIAxes.FontSize = 20;

            focus(app.UIFigure);

            drawnow;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Video Frame')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.OpenvideoButton = uibutton(app.UIFigure, 'push');
            app.OpenvideoButton.ButtonPushedFcn = createCallbackFcn(app, @OpenvideoButtonPushed, true);
            app.OpenvideoButton.Text = 'Open video';
            app.PlayvideoButton = uibutton(app.UIFigure, 'state');
            app.PlayvideoButton.ValueChangedFcn = createCallbackFcn(app, @PlayvideoButtonValueChanged, true);
            app.PlayvideoButton.Enable = 'off';
            app.PlayvideoButton.Text = 'Play video';
            app.LabelUnlabelframeButton = uibutton(app.UIFigure, 'push');
            app.LabelUnlabelframeButton.ButtonPushedFcn = createCallbackFcn(app, @LabelUnlabelframeButtonPushed, true);
            app.LabelUnlabelframeButton.Enable = 'off';
            app.LabelUnlabelframeButton.Text = 'Label/Unlabel frame';
            app.SavedataButton = uibutton(app.UIFigure, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Enable = 'off';
            app.SavedataButton.Text = 'Save data';
            app.FasterButton = uibutton(app.UIFigure, 'push');
            app.FasterButton.ButtonPushedFcn = createCallbackFcn(app, @FasterButtonPushed, true);
            app.FasterButton.Enable = 'off';
            app.FasterButton.Text = 'Faster';
            app.SlowerButton = uibutton(app.UIFigure, 'push');
            app.SlowerButton.ButtonPushedFcn = createCallbackFcn(app, @SlowerButtonPushed, true);
            app.SlowerButton.Enable = 'off';
            app.SlowerButton.Text = 'Slower';
            app.PreviousframeButton = uibutton(app.UIFigure, 'push');
            app.PreviousframeButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousframeButtonPushed, true);
            app.PreviousframeButton.Enable = 'off';
            app.PreviousframeButton.Text = 'Previous frame';
            app.NextframeButton = uibutton(app.UIFigure, 'push');
            app.NextframeButton.ButtonPushedFcn = createCallbackFcn(app, @NextframeButtonPushed, true);
            app.NextframeButton.Enable = 'off';
            app.NextframeButton.Text = 'Next frame';
            app.framesbackButton = uibutton(app.UIFigure, 'push');
            app.framesbackButton.ButtonPushedFcn = createCallbackFcn(app, @framesbackButtonPushed, true);
            app.framesbackButton.Enable = 'off';
            app.framesbackButton.Text = '10 frames back';
            app.framesforwardButton = uibutton(app.UIFigure, 'push');
            app.framesforwardButton.ButtonPushedFcn = createCallbackFcn(app, @framesforwardButtonPushed, true);
            app.framesforwardButton.Enable = 'off';
            app.framesforwardButton.Text = '10 frames forward';
            app.secbackButton = uibutton(app.UIFigure, 'push');
            app.secbackButton.ButtonPushedFcn = createCallbackFcn(app, @secbackButtonPushed, true);
            app.secbackButton.Enable = 'off';
            app.secbackButton.Text = '1 sec back';
            app.secforwardButton = uibutton(app.UIFigure, 'push');
            app.secforwardButton.ButtonPushedFcn = createCallbackFcn(app, @secforwardButtonPushed, true);
            app.secforwardButton.Enable = 'off';
            app.secforwardButton.Text = '1 sec forward';
            app.LoaddataButton = uibutton(app.UIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.LastlabeledframeButton = uibutton(app.UIFigure, 'push');
            app.LastlabeledframeButton.ButtonPushedFcn = createCallbackFcn(app, @LastlabeledframeButtonPushed, true);
            app.LastlabeledframeButton.Enable = 'off';
            app.LastlabeledframeButton.Text = 'Last labeled frame';
            app.LatestlabeledframeButton = uibutton(app.UIFigure, 'push');
            app.LatestlabeledframeButton.ButtonPushedFcn = createCallbackFcn(app, @LatestlabeledframeButtonPushed, true);
            app.LatestlabeledframeButton.Enable = 'off';
            app.LatestlabeledframeButton.Text = 'Latest labeled frame';
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Placeholder = '/0';
            app.JumptolabeledframeSpinnerLabel = uilabel(app.UIFigure);
            app.JumptolabeledframeSpinnerLabel.Enable = 'off';
            app.JumptolabeledframeSpinnerLabel.Text = 'Jump to labeled frame';
            app.JumptolabeledframeSpinner = uispinner(app.UIFigure);
            app.JumptolabeledframeSpinner.LowerLimitInclusive = 'off';
            app.JumptolabeledframeSpinner.ValueChangingFcn = createCallbackFcn(app, @JumptolabeledframeSpinnerValueChanging, true);
            app.JumptolabeledframeSpinner.Limits = [0 1];
            app.JumptolabeledframeSpinner.RoundFractionalValues = 'on';
            app.JumptolabeledframeSpinner.Enable = 'off';
            app.JumptolabeledframeSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ps_video_labeler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end